classdef cryptography < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CRYPTOGRAPHYAPPLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Modular256ArithmeticTab       matlab.ui.container.Tab
        EncryptionDecryptionLabel     matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        TEXTTab                       matlab.ui.container.Tab
        DecryptButton                 matlab.ui.control.Button
        DecryptedTextTextArea         matlab.ui.control.TextArea
        DecryptedTextTextAreaLabel    matlab.ui.control.Label
        EncryptedTextTextArea         matlab.ui.control.TextArea
        EncryptedTextTextAreaLabel    matlab.ui.control.Label
        EncryptButton                 matlab.ui.control.Button
        EntertexttoEncryptTextArea    matlab.ui.control.TextArea
        EntertexttoEncryptLabel       matlab.ui.control.Label
        FILETab                       matlab.ui.container.Tab
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        DecryptedContentLabel         matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        EncryptedContentLabel         matlab.ui.control.Label
        DecryptButton_3               matlab.ui.control.Button
        EncryptButton_3               matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        OriginalFileContentLabel      matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        BrowseFileButton              matlab.ui.control.Button
        IMAGETab                      matlab.ui.container.Tab
        DecryptImageButton            matlab.ui.control.Button
        EncryptImageButton            matlab.ui.control.Button
        BrowseImageButton             matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        LatticeBasedCryptographyTab   matlab.ui.container.Tab
        EncryptionDecryptionLabel_2   matlab.ui.control.Label
        TabGroup3                     matlab.ui.container.TabGroup
        TEXTTab_2                     matlab.ui.container.Tab
        DecryptedTextTextArea_2       matlab.ui.control.TextArea
        DecryptedTextTextArea_2Label  matlab.ui.control.Label
        EncryptedTextTextArea_2       matlab.ui.control.TextArea
        EncryptedTextTextArea_2Label  matlab.ui.control.Label
        EntertexttoEncryptTextArea_2  matlab.ui.control.TextArea
        EntertexttoEncryptTextArea_2Label  matlab.ui.control.Label
        DecryptButton_2               matlab.ui.control.Button
        EncryptButton_2               matlab.ui.control.Button
        FILETab_2                     matlab.ui.container.Tab
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        TextArea_6                    matlab.ui.control.TextArea
        DecryptedContentLabel_2       matlab.ui.control.Label
        TextArea_5                    matlab.ui.control.TextArea
        EncryptedContentLabel_2       matlab.ui.control.Label
        DecryptButton_4               matlab.ui.control.Button
        EncryptButton_4               matlab.ui.control.Button
        TextArea_4                    matlab.ui.control.TextArea
        OriginalFileContentLabel_2    matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        BrowseFileButton_2            matlab.ui.control.Button
        IMAGETab_2                    matlab.ui.container.Tab
        DecryptImageButton_2          matlab.ui.control.Button
        EncryptImageButton_2          matlab.ui.control.Button
        BrowseImageButton_2           matlab.ui.control.Button
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        KeyMatrix        % To store encryption key
        BlockSize = 3;   % Default block size
        ModValue = 256;  % For modulo 256 arithmetic
        LWE_A          % Public matrix A
        LWE_b          % Public vector b
        LWE_s          % Secret key s
        LWE_U          % Ciphertext matrix U
        LWE_V          % Ciphertext vector V
        LWE_q = 8192   % Modulus
        LWE_n = 50     % Dimension of secret
        LWE_sigma = 0.05 % Std deviation of noise
        LWE_weight = 3  % Hamming weight
        originalImage % stores original image
        encryptedImage % stores encrypted image
        decryptedImage % stores decrypted image
        OriginalImage          % stores original image
        EncryptedImage         % stores encrypted image
        DecryptedImage         % stores decrypted image
        LWEKeys                % struct to hold A, b, s
        ImageFilePath          % path of selected image
        ImageFileName          % name of selected image
        EncryptedFilePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            plainText = char(app.EntertexttoEncryptTextArea.Value);
            L = numel(plainText);
            blockSize = app.BlockSize;
            modValue = app.ModValue;

            padLen = mod(-L, blockSize);
            if padLen > 0
                plainText = [plainText, repmat(' ', 1, padLen)];
                L = L + padLen;
            end
            textASCII = double(plainText);

            % Generate Key
            while true
                key = randi([1, modValue - 1], blockSize, blockSize);
                if gcd(round(det(key)), modValue) == 1
                break;
                end
            end
            app.KeyMatrix = key;

            encryptedASCII = zeros(L, 1);
            for i = 1:blockSize:L
                chunk = textASCII(i:i+blockSize-1)';
                encryptedASCII(i:i+blockSize-1) = mod(key * chunk, modValue);
            end
            encryptedText = char(mod(encryptedASCII', 256));
            app.EncryptedTextTextArea.Value = encryptedText;  
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            encryptedText = char(app.EncryptedTextTextArea.Value);
            blockSize = app.BlockSize;
            modValue = app.ModValue;

            encASCII = double(encryptedText(:));

            % Inverse of Key Matrix
            keyInv = modInverseMatrix(app.KeyMatrix, app.ModValue);

            N = numel(encASCII);
            decryptedASCII = zeros(N, 1);
            for i = 1:blockSize:N
                chunk = encASCII(i:i+blockSize-1);
                decryptedASCII(i:i+blockSize-1) = mod(keyInv * chunk, modValue);
            end
            decryptedText = strtrim(char(mod(decryptedASCII', 256)));
            app.DecryptedTextTextArea.Value = decryptedText;

        function invA = modInverseMatrix(A, M)
            n = size(A, 1);
            d = round(det(A));
            [g, x] = gcd(d, M);
            if g ~= 1
                error('Key not invertible mod %d', M);
            end
            detInv = mod(x, M);
            C = zeros(n);
            for ii = 1:n
                for jj = 1:n
                    sub = A([1:ii-1, ii+1:end], [1:jj-1, jj+1:end]);
                    C(ii, jj) = (-1)^(ii+jj) * round(det(sub));
                end
            end
            adj = C';
            invA = mod(detInv * adj, M);
            end
        end

        % Button pushed function: EncryptButton_2
        function EncryptButton_2Pushed(app, event)
            plainText = app.EntertexttoEncryptTextArea_2.Value;  % Get user input
            m = double(char(plainText(:)));      % Convert to ASCII
            L = numel(m);

            % Key generation
            app.LWE_A = randi([0, app.LWE_q - 1], app.LWE_n, app.LWE_n);
            app.LWE_s = randi([0, app.LWE_q - 1], app.LWE_n, 1);
            e = round(app.LWE_sigma * randn(app.LWE_n, 1));
            app.LWE_b = mod(app.LWE_A * app.LWE_s + e, app.LWE_q);

            % Encryption
            U = zeros(app.LWE_n, L);
            V = zeros(L, 1);
            for i = 1:L
                r = zeros(app.LWE_n, 1);
                idx = randperm(app.LWE_n, app.LWE_weight);
                r(idx) = 1;
                U(:, i) = mod(app.LWE_A' * r, app.LWE_q);
                V(i)    = mod(app.LWE_b' * r + m(i), app.LWE_q);
            end

            app.LWE_U = U;
            app.LWE_V = V;

            cipherText = char(mod(V, 256))';
            app.EncryptedTextTextArea_2.Value = cipherText;
        end

        % Button pushed function: DecryptButton_2
        function DecryptButton_2Pushed(app, event)
            V = app.LWE_V;
            U = app.LWE_U;
            L = numel(V);
            m_dec = zeros(L, 1);

            for i = 1:L
                dec = mod(V(i) - app.LWE_s' * U(:, i), app.LWE_q);
                if dec > app.LWE_q / 2
                    dec = dec - app.LWE_q;
                end
                m_dec(i) = round(dec);
            end

            decText = char(m_dec');
            app.DecryptedTextTextArea_2.Value = decText;
        end

        % Button pushed function: BrowseImageButton_2
        function BrowseImageButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end

            fullImagePath = fullfile(path, file);
            app.ImageFilePath = path;
            app.ImageFileName = file;
            app.OriginalImage = imread(fullImagePath);

            imshow(app.OriginalImage, 'Parent', app.UIAxes_5);
            title(app.UIAxes_5, 'Original Image');
        end

        % Button pushed function: EncryptImageButton_2
        function EncryptImageButton_2Pushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'No image loaded. Please browse first.', 'Error');
                return;
            end

            % --- LWE Parameters ---
            q = 8192;
            n = 50;
            sigma = 0.05;
            weight = 3;

            % Key Generation
            A = randi([0,q-1], n, n);
            s = randi([0,q-1], n, 1);
            e = round(sigma * randn(n,1));
            b = mod(A*s + e, q);

            % Store keys
            app.LWEKeys = struct('A', A, 'b', b, 's', s);

            % Encrypt Image
            I = app.OriginalImage;
            [R,C,CH] = size(I);
            encI = zeros(R,C,CH,'uint8');

            for ch = 1:CH
                pix = double(I(:,:,ch));
                pix = pix(:);
                L = numel(pix);
                Uc = zeros(n, L);
                Vc = zeros(L,1);
                for i = 1:L
                    r = zeros(n,1);
                    idx = randperm(n, weight);
                    r(idx) = 1;
                    Uc(:,i) = mod(A' * r, q);
                    Vc(i) = mod(b' * r + pix(i), q);
                end
                encI(:,:,ch) = reshape(uint8(mod(Vc,256)), R, C);
                save(fullfile(app.ImageFilePath, sprintf('lwe_img_ch%d.mat', ch)), 'Uc', 'Vc');
            end

            app.EncryptedImage = encI;
            imshow(encI, 'Parent', app.UIAxes_4); 
            title(app.UIAxes_4, 'Encrypted Image');
        end

        % Button pushed function: DecryptImageButton_2
        function DecryptImageButton_2Pushed(app, event)
            if isempty(app.EncryptedImage) || isempty(app.LWEKeys)
                uialert(app.UIFigure, 'Please encrypt an image first.', 'Error');
                return;
            end

            s = app.LWEKeys.s;
            q = 8192;

            encI = app.EncryptedImage;
            [R,C,CH] = size(encI);
            decI = zeros(R,C,CH,'uint8');

            for ch = 1:CH
                ctFile = fullfile(app.ImageFilePath, sprintf('lwe_img_ch%d.mat', ch));
                if ~isfile(ctFile)
                    uialert(app.UIFigure, sprintf('Missing ciphertext file: %s', ctFile), 'Error');
                    return;
                end
                load(ctFile, 'Uc', 'Vc');
                L = numel(Vc);
                m_dec = zeros(L,1);
                for i = 1:L
                    dec = mod(Vc(i) - s' * Uc(:,i), q);
                    if dec > q/2
                        dec = dec - q;
                    end
                    m_dec(i) = round(dec);
                end
                decI(:,:,ch) = reshape(uint8(m_dec), R, C);
            end

            app.DecryptedImage = decI;
            imshow(decI, 'Parent', app.UIAxes_6);
            title(app.UIAxes_6, 'Decrypted Image');
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp'}, 'Select an image');
            if isequal(file, 0)
                return;
            end
            imgPath = fullfile(path, file);
            app.originalImage = imread(imgPath);
            imshow(app.originalImage, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Original Image');
        end

        % Button pushed function: EncryptImageButton
        function EncryptImageButtonPushed(app, event)
            if isempty(app.originalImage)
                uialert(app.UIFigure, 'No image selected.', 'Error');
                return;
            end

            img = app.originalImage;
            [rows, cols, ch] = size(img);
            padR = mod(app.BlockSize - mod(rows, app.BlockSize), app.BlockSize);
            padC = mod(app.BlockSize - mod(cols, app.BlockSize), app.BlockSize);
            paddedImage = customPadImage(img, padR, padC);

            while true
                key = randi([1 app.ModValue-1], app.BlockSize);
                if gcd(round(det(key)), app.ModValue) == 1
                    break;
                end
            end
            app.KeyMatrix = key;

            encImg = zeros(size(paddedImage), 'uint8');
            [pr, pc, ~] = size(paddedImage);
            for c = 1:ch
                for i = 1:app.BlockSize:pr
                    for j = 1:app.BlockSize:pc
                        block = double(paddedImage(i:i+app.BlockSize-1, j:j+app.BlockSize-1, c));
                        encryptedBlock = mod(key * block, app.ModValue);
                        encImg(i:i+app.BlockSize-1, j:j+app.BlockSize-1, c) = uint8(encryptedBlock);
                    end
                end
            end

            app.encryptedImage = encImg;
            imshow(app.encryptedImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Encrypted Image');

        function padded = customPadImage(image, padRows, padCols)
            [rows, cols, channels] = size(image);
            newRows = rows + padRows;
            newCols = cols + padCols;
            padded = zeros(newRows, newCols, channels, 'like', image);
            padded(1:rows, 1:cols, :) = image;
        end
 

        end

        % Button pushed function: DecryptImageButton
        function DecryptImageButtonPushed(app, event)
            if isempty(app.encryptedImage) || isempty(app.KeyMatrix)
                uialert(app.UIFigure, 'Encrypt image first.', 'Error');
                return;
            end

            keyInv = modInverseMatrix(app.KeyMatrix, app.ModValue);
            [rows, cols, ch] = size(app.encryptedImage);
            decImg = zeros(size(app.encryptedImage), 'uint8');

            for c = 1:ch
                for i = 1:app.BlockSize:rows
                    for j = 1:app.BlockSize:cols
                        block = double(app.encryptedImage(i:i+app.BlockSize-1, j:j+app.BlockSize-1, c));
                        decryptedBlock = mod(keyInv * block, app.ModValue);
                        decImg(i:i+app.BlockSize-1, j:j+app.BlockSize-1, c) = uint8(decryptedBlock);
                    end
                end
            end

            % Remove padding
            [origR, origC, ~] = size(app.originalImage);
            app.decryptedImage = decImg(1:origR, 1:origC, :);
            imshow(app.decryptedImage, 'Parent', app.UIAxes_3);
            title(app.UIAxes_3, 'Decrypted Image');

       function invA = modInverseMatrix(A, M)
            n = size(A, 1);
            d = round(det(A));
            [g, x] = gcd(d, M);
            if g ~= 1
                error('Key not invertible mod %d', M);
            end
            detInv = mod(x, M);
            C = zeros(n);
            for ii = 1:n
                for jj = 1:n
                    sub = A([1:ii-1, ii+1:end], [1:jj-1, jj+1:end]);
                    C(ii, jj) = (-1)^(ii+jj) * round(det(sub));
                end
            end
            adj = C';
            invA = mod(detInv * adj, M);
            end
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            app.EditField.Value = fullPath;

            % Load and display file content
            fid = fopen(fullPath, 'r');
            content = fread(fid, '*char')';
            fclose(fid);
    
            app.TextArea.Value = content;
        end

        % Button pushed function: EncryptButton_3
        function EncryptButton_3Pushed(app, event)
            app.Label.Text = '';  % In EncryptButtonPushed
            text = strjoin(app.TextArea.Value, newline);  % Combines lines into a single string
            if isempty(text)
                uialert(app.UIFigure, 'No text to encrypt!', 'Error');
                return;
            end

            text = char(text);  % Ensure it's a string
            L = length(text);
            padLen = mod(-L, app.BlockSize);
            if padLen > 0
                text = [text, repmat(' ', 1, padLen)];
            end
            textASCII = double(text);

            % Generate valid random key
            while true
                key = randi([1, app.ModValue - 1], app.BlockSize);
                if gcd(round(det(key)), app.ModValue) == 1
                    break;
                end
            end
            app.KeyMatrix = key;

            % Encrypt
            encryptedASCII = zeros(size(textASCII));
            for i = 1:app.BlockSize:L
                chunk = textASCII(i:i+app.BlockSize-1)';
                encryptedASCII(i:i+app.BlockSize-1) = mod(key * chunk, app.ModValue);
            end

            encryptedText = char(mod(encryptedASCII', 256));
            app.TextArea_2.Value = {encryptedText};

            % Save encrypted file
            [~, name, ~] = fileparts(app.EditField.Value);
            encryptedFile = fullfile(fileparts(app.EditField.Value), ['encrypted_' name '.txt']);
            fid = fopen(encryptedFile, 'w');
            fwrite(fid, encryptedText, 'char');
            fclose(fid);

            % Update status label
            app.Label.Text = ['Encrypted content saved to: ', encryptedFile];
        end

        % Button pushed function: DecryptButton_3
        function DecryptButton_3Pushed(app, event)
            app.Label_2.Text = '';  % In DecryptButtonPushed

            if isempty(app.KeyMatrix)
                uialert(app.UIFigure, 'No encryption key found!', 'Error');
                return;
            end

            encryptedText = strjoin(app.TextArea_2.Value, newline);
            if isempty(encryptedText)
                uialert(app.UIFigure, 'No encrypted text to decrypt!', 'Error');
                return;
            end

            encASCII = double(encryptedText);
            keyInv = modInverseMatrix(app.KeyMatrix, app.ModValue);

            decASCII = zeros(size(encASCII));
            for i = 1:app.BlockSize:length(encASCII)
                if i + app.BlockSize - 1 > length(encASCII)
                    break;  % avoid overflow
                end
                chunk = encASCII(i:i+app.BlockSize-1)';
                decASCII(i:i+app.BlockSize-1) = mod(keyInv * chunk, app.ModValue);
            end
            decText = char(mod(decASCII', 256));
            app.TextArea_3.Value = {strtrim(decText)};

            % Save decrypted file
            [~, name, ~] = fileparts(app.EditField.Value);
            decFile = fullfile(fileparts(app.EditField.Value), ['decrypted_' name '.txt']);
            fid = fopen(decFile, 'w');
            fwrite(fid, decText, 'char');
            fclose(fid);
            % Update status label
            app.Label_2.Text = ['Decrypted content saved to: ', decFile];
        
        
            function invA = modInverseMatrix(A, M)
                n = size(A, 1);
                d = round(det(A));
                [g, x] = gcd(d, M);
                if g ~= 1
                    error('Matrix not invertible under mod %d', M);
                end
                detInv = mod(x, M);

                C = zeros(n);
                for ii = 1:n
                    for jj = 1:n
                        sub = A([1:ii-1, ii+1:end], [1:jj-1, jj+1:end]);
                        C(ii,jj) = (-1)^(ii+jj) * round(det(sub));
                    end
                end
                adj = C';
                invA = mod(detInv * adj, M);
            end
        end

        % Button pushed function: BrowseFileButton_2
        function BrowseFileButton_2Pushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            app.EditField_2.Value = fullPath;

            % Load file content
            fid = fopen(fullPath, 'r');
            content = fread(fid, '*char')';
            fclose(fid);
            app.TextArea_4.Value = content;
        end

        % Button pushed function: EncryptButton_4
        function EncryptButton_4Pushed(app, event)
            app.Label_3.Text = '';

            % Parameters
            q = 8192;
            n = 50;
            sigma = 0.05;
            weight = 3;

            % Read input
            text = strjoin(app.TextArea_4.Value, newline);
            if isempty(text)
                uialert(app.UIFigure, 'No text to encrypt!', 'Error');
                return;
            end

            m = double(text(:));  % Convert to ASCII
            L = numel(m);
            U = zeros(n, L);
            V = zeros(L, 1);

            % Generate LWE keys (store internally)
            app.LWE_A = randi([0, q-1], n, n);
            app.LWE_s = randi([0, q-1], n, 1);
            e = round(sigma * randn(n, 1));
            app.LWE_b = mod(app.LWE_A * app.LWE_s + e, q);

            % Encrypt
            for i = 1:L
                r = zeros(n, 1);
                idx = randperm(n, weight);
                r(idx) = 1;
                U(:, i) = mod(app.LWE_A' * r, q);
                V(i) = mod(app.LWE_b' * r + m(i), q);
            end

            % Save ciphertext only
            [~, name, ~] = fileparts(app.EditField_2.Value);
            app.EncryptedFilePath = fullfile(fileparts(app.EditField_2.Value), ['lwe_enc_' name '.mat']);
            save(app.EncryptedFilePath, 'U', 'V');

            % Preview encrypted text
            encPreview = char(mod(V, 256))';
            app.TextArea_5.Value = {encPreview};

            app.Label_3.Text = ['Encrypted file saved to: ' app.EncryptedFilePath];
        
        end

        % Button pushed function: DecryptButton_4
        function DecryptButton_4Pushed(app, event)
            app.Label_4.Text = '';

            % Ensure keys exist
            if isempty(app.LWE_A) || isempty(app.LWE_b) || isempty(app.LWE_s)
                uialert(app.UIFigure, 'Encryption key not found in memory!', 'Error');
                return;
            end

            % Load ciphertext from the known encrypted file path
            if isempty(app.EncryptedFilePath) || ~isfile(app.EncryptedFilePath)
                uialert(app.UIFigure, 'Encrypted file not found!', 'Error');
                return;
            end
            load(app.EncryptedFilePath, 'U', 'V');

            % Decrypt
            q = 8192;
            L = numel(V);
            m_dec = zeros(L, 1);
            for i = 1:L
                dec = mod(V(i) - app.LWE_s' * U(:, i), q);
                if dec > q/2
                    dec = dec - q;
                end
                m_dec(i) = round(dec);
            end

            decText = char(m_dec');
            app.TextArea_6.Value = {strtrim(decText)};

            % Save decrypted file
            [~, name, ~] = fileparts(app.EditField_2.Value);
            decFile = fullfile(fileparts(app.EditField_2.Value), ['lwe_decrypted_' name '.txt']);
            fid = fopen(decFile, 'w');
            fwrite(fid, decText, 'char');
            fclose(fid);

            app.Label_4.Text = ['Decrypted content saved to: ' decFile];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Modular256ArithmeticTab = uitab(app.TabGroup);
            app.Modular256ArithmeticTab.Title = 'Modular 256 Arithmetic';
            app.TabGroup2 = uitabgroup(app.Modular256ArithmeticTab);
            app.TEXTTab = uitab(app.TabGroup2);
            app.TEXTTab.Title = 'TEXT';
            app.EntertexttoEncryptLabel = uilabel(app.TEXTTab);
            app.EntertexttoEncryptLabel.Text = 'Enter text to Encrypt:';
            app.EntertexttoEncryptTextArea = uitextarea(app.TEXTTab);
            app.EncryptButton = uibutton(app.TEXTTab, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.EncryptedTextTextAreaLabel = uilabel(app.TEXTTab);
            app.EncryptedTextTextAreaLabel.Text = 'Encrypted Text:';
            app.EncryptedTextTextArea = uitextarea(app.TEXTTab);
            app.DecryptedTextTextAreaLabel = uilabel(app.TEXTTab);
            app.DecryptedTextTextAreaLabel.Text = 'Decrypted Text:';
            app.DecryptedTextTextArea = uitextarea(app.TEXTTab);
            app.DecryptButton = uibutton(app.TEXTTab, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.FILETab = uitab(app.TabGroup2);
            app.FILETab.Title = 'FILE';
            app.BrowseFileButton = uibutton(app.FILETab, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File:';
            app.EditField = uieditfield(app.FILETab, 'text');
            app.OriginalFileContentLabel = uilabel(app.FILETab);
            app.OriginalFileContentLabel.Text = 'Original File Content';
            app.TextArea = uitextarea(app.FILETab);
            app.EncryptButton_3 = uibutton(app.FILETab, 'push');
            app.EncryptButton_3.ButtonPushedFcn = createCallbackFcn(app, @EncryptButton_3Pushed, true);
            app.EncryptButton_3.Text = 'Encrypt';
            app.DecryptButton_3 = uibutton(app.FILETab, 'push');
            app.DecryptButton_3.ButtonPushedFcn = createCallbackFcn(app, @DecryptButton_3Pushed, true);
            app.DecryptButton_3.Text = 'Decrypt';
            app.EncryptedContentLabel = uilabel(app.FILETab);
            app.EncryptedContentLabel.Text = 'Encrypted Content';
            app.TextArea_2 = uitextarea(app.FILETab);
            app.DecryptedContentLabel = uilabel(app.FILETab);
            app.DecryptedContentLabel.Text = 'Decrypted Content';
            app.TextArea_3 = uitextarea(app.FILETab);
            app.Label = uilabel(app.FILETab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.FILETab);
            app.Label_2.Text = '';
            app.IMAGETab = uitab(app.TabGroup2);
            app.IMAGETab.Title = 'IMAGE';
            app.UIAxes = uiaxes(app.IMAGETab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.IMAGETab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.IMAGETab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.BrowseImageButton = uibutton(app.IMAGETab, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image:';
            app.EncryptImageButton = uibutton(app.IMAGETab, 'push');
            app.EncryptImageButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptImageButtonPushed, true);
            app.EncryptImageButton.Text = 'Encrypt Image:';
            app.DecryptImageButton = uibutton(app.IMAGETab, 'push');
            app.DecryptImageButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptImageButtonPushed, true);
            app.DecryptImageButton.Text = 'Decrypt Image:';
            app.EncryptionDecryptionLabel = uilabel(app.Modular256ArithmeticTab);
            app.EncryptionDecryptionLabel.Text = 'Encryption & Decryption';
            app.LatticeBasedCryptographyTab = uitab(app.TabGroup);
            app.LatticeBasedCryptographyTab.Title = 'Lattice-Based Cryptography';
            app.TabGroup3 = uitabgroup(app.LatticeBasedCryptographyTab);
            app.TEXTTab_2 = uitab(app.TabGroup3);
            app.TEXTTab_2.Title = 'TEXT';
            app.EncryptButton_2 = uibutton(app.TEXTTab_2, 'push');
            app.EncryptButton_2.ButtonPushedFcn = createCallbackFcn(app, @EncryptButton_2Pushed, true);
            app.EncryptButton_2.Text = 'Encrypt';
            app.DecryptButton_2 = uibutton(app.TEXTTab_2, 'push');
            app.DecryptButton_2.ButtonPushedFcn = createCallbackFcn(app, @DecryptButton_2Pushed, true);
            app.DecryptButton_2.Text = 'Decrypt';
            app.EntertexttoEncryptTextArea_2Label = uilabel(app.TEXTTab_2);
            app.EntertexttoEncryptTextArea_2Label.Text = 'Enter text to Encrypt:';
            app.EntertexttoEncryptTextArea_2 = uitextarea(app.TEXTTab_2);
            app.EncryptedTextTextArea_2Label = uilabel(app.TEXTTab_2);
            app.EncryptedTextTextArea_2Label.Text = 'Encrypted Text:';
            app.EncryptedTextTextArea_2 = uitextarea(app.TEXTTab_2);
            app.DecryptedTextTextArea_2Label = uilabel(app.TEXTTab_2);
            app.DecryptedTextTextArea_2Label.Text = 'Decrypted Text:';
            app.DecryptedTextTextArea_2 = uitextarea(app.TEXTTab_2);
            app.FILETab_2 = uitab(app.TabGroup3);
            app.FILETab_2.Title = 'FILE';
            app.BrowseFileButton_2 = uibutton(app.FILETab_2, 'push');
            app.BrowseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButton_2Pushed, true);
            app.BrowseFileButton_2.Text = 'Browse File:';
            app.EditField_2 = uieditfield(app.FILETab_2, 'text');
            app.OriginalFileContentLabel_2 = uilabel(app.FILETab_2);
            app.OriginalFileContentLabel_2.Text = 'Original File Content';
            app.TextArea_4 = uitextarea(app.FILETab_2);
            app.EncryptButton_4 = uibutton(app.FILETab_2, 'push');
            app.EncryptButton_4.ButtonPushedFcn = createCallbackFcn(app, @EncryptButton_4Pushed, true);
            app.EncryptButton_4.Text = 'Encrypt';
            app.DecryptButton_4 = uibutton(app.FILETab_2, 'push');
            app.DecryptButton_4.ButtonPushedFcn = createCallbackFcn(app, @DecryptButton_4Pushed, true);
            app.DecryptButton_4.Text = 'Decrypt';
            app.EncryptedContentLabel_2 = uilabel(app.FILETab_2);
            app.EncryptedContentLabel_2.Text = 'Encrypted Content';
            app.TextArea_5 = uitextarea(app.FILETab_2);
            app.DecryptedContentLabel_2 = uilabel(app.FILETab_2);
            app.DecryptedContentLabel_2.Text = 'Decrypted Content';
            app.TextArea_6 = uitextarea(app.FILETab_2);
            app.Label_3 = uilabel(app.FILETab_2);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.FILETab_2);
            app.Label_4.Text = '';
            app.IMAGETab_2 = uitab(app.TabGroup3);
            app.IMAGETab_2.Title = 'IMAGE';
            app.UIAxes_4 = uiaxes(app.IMAGETab_2);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.IMAGETab_2);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.IMAGETab_2);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.BrowseImageButton_2 = uibutton(app.IMAGETab_2, 'push');
            app.BrowseImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButton_2Pushed, true);
            app.BrowseImageButton_2.Text = 'Browse Image:';
            app.EncryptImageButton_2 = uibutton(app.IMAGETab_2, 'push');
            app.EncryptImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @EncryptImageButton_2Pushed, true);
            app.EncryptImageButton_2.Text = 'Encrypt Image:';
            app.DecryptImageButton_2 = uibutton(app.IMAGETab_2, 'push');
            app.DecryptImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @DecryptImageButton_2Pushed, true);
            app.DecryptImageButton_2.Text = 'Decrypt Image:';
            app.EncryptionDecryptionLabel_2 = uilabel(app.LatticeBasedCryptographyTab);
            app.EncryptionDecryptionLabel_2.Text = 'Encryption & Decryption';
            app.CRYPTOGRAPHYAPPLabel = uilabel(app.UIFigure);
            app.CRYPTOGRAPHYAPPLabel.Text = 'CRYPTOGRAPHY APP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cryptography

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end